classdef Sim3D_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        TabGroup2                matlab.ui.container.TabGroup
        SetupTab                 matlab.ui.container.Tab
        TabGroup_2               matlab.ui.container.TabGroup
        RocketTab                matlab.ui.container.Tab
        Label                    matlab.ui.control.Label
        RocketTextArea           matlab.ui.control.TextArea
        RocketOpenButton         matlab.ui.control.Button
        RocketSaveButton         matlab.ui.control.Button
        RocketSaveAsButton       matlab.ui.control.Button
        FileEditFieldLabel       matlab.ui.control.Label
        RocketFileField          matlab.ui.control.EditField
        EnvironmentTab           matlab.ui.container.Tab
        EnvironmentTextArea      matlab.ui.control.TextArea
        EnvironmentOpenButton    matlab.ui.control.Button
        EnvironmentSaveButton    matlab.ui.control.Button
        EnvironmentSaveAsButton  matlab.ui.control.Button
        FileEditFieldLabel_2     matlab.ui.control.Label
        EnvironmentFileField     matlab.ui.control.EditField
        SimulationTab            matlab.ui.container.Tab
        ComputeButton            matlab.ui.control.Button
        TabGroup                 matlab.ui.container.TabGroup
        TrajectoryTab            matlab.ui.container.Tab
        TrajectoryAxes           matlab.ui.control.UIAxes
        TimefunctionsTab         matlab.ui.container.Tab
        TimeAltitudeAxes         matlab.ui.control.UIAxes
        TimeVelocityAxes         matlab.ui.control.UIAxes
        TimeAccelerationAxes     matlab.ui.control.UIAxes
        AeroTab                  matlab.ui.container.Tab
        marginAxes               matlab.ui.control.UIAxes
        alphaAxes                matlab.ui.control.UIAxes
        CNaAxes                  matlab.ui.control.UIAxes
        CdAxes                   matlab.ui.control.UIAxes
        MassTab                  matlab.ui.container.Tab
        massAxes                 matlab.ui.control.UIAxes
        CMAxes                   matlab.ui.control.UIAxes
        IlAxes                   matlab.ui.control.UIAxes
        IrAxes                   matlab.ui.control.UIAxes
        ComputingLampLabel       matlab.ui.control.Label
        ComputingLamp            matlab.ui.control.Lamp
        MessagesTextAreaLabel    matlab.ui.control.Label
        MessagesTextArea         matlab.ui.control.TextArea
        AirbrakesTab             matlab.ui.container.Tab
        AirbrakesAxes            matlab.ui.control.UIAxes
        TargetmEditFieldLabel    matlab.ui.control.Label
        TargetEditField          matlab.ui.control.NumericEditField
        AirbrakesComputeButton   matlab.ui.control.Button
        PreviewTextAreaLabel     matlab.ui.control.Label
        PreviewTextArea          matlab.ui.control.TextArea
        N_hLabel                 matlab.ui.control.Label
        N_hEditField             matlab.ui.control.NumericEditField
        N_abEditFieldLabel       matlab.ui.control.Label
        N_abEditField            matlab.ui.control.NumericEditField
    end


    properties (Access = private)
        RocketFilePath = 'Rocket_Definition_Final.txt';
        EnvironmentFilePath = 'Environnement_Definition.txt';
        SimOutputFilePath = 'Simulation_outputs.txt';
    end

    methods (Access = private)
    
        function displayMessage(app, str)
            app.MessagesTextArea.Value{end+1} = str;
        end
        
        function filePath = File2TextArea(app, textArea)
            
            % Clear text area
            textArea.Value = {''};
            
            % Open rocket file
            [fileName, folderPath] = uigetfile('*.txt');
            
            % Check if the file was opened or cancel was pressed
            if fileName == 0
                file = [];
               return 
            end
            
            filePath = [folderPath, fileName];
            fid = fopen(filePath);
            
            % Display content in text area
            while(~feof(fid))
                textArea.Value{end+1} = fgetl(fid);
            end
            
            textArea.Value = textArea.Value(2:end);
            
            fclose(fid);
            
        end
        
        function filePath = TextArea2File(app, filePath, textArea)
            % Check if file was opened before, else create new file
            if(isempty(filePath))
                [fileName, folderPath] = uiputfile('*.txt');
                % Check if the file was opened or cancel was pressed
                if fileName == 0
                    filePath = [];
                   return 
                end
                filePath = [folderPath, fileName];
            end
            
            % save file
            fid = fopen(filePath, 'w');
            n = size(textArea.Value);
            
            for i = 1:n(1)
               fprintf(fid, '%s\n', textArea.Value{i});
            end
            
            fclose(fid);
        end
        
        function filePath = TextArea2NewFile(app, filePath, textArea)
            
            [fileName, folderPath] = uiputfile('*.txt');
            % Check if the file was opened or cancel was pressed
            if fileName == 0
               return 
            end         
            filePath = [folderPath, fileName];
            
            % save file
            fid = fopen(filePath, 'w');
            n = size(textArea.Value);
            
            for i = 1:n(1)
               fprintf(fid, '%s\n', textArea.Value{i});
            end
            
            fclose(fid);
        end
        
        function loadFile(app, filePath, textArea)
           
            fid = fopen(filePath);
            
            % Display content in text area
            while(~feof(fid))
                textArea.Value{end+1} = fgetl(fid);
            end
            
            textArea.Value = textArea.Value(2:end);
            
            fclose(fid);
            
        end
        
        function fullName = getFileName(app, filePath)
            [~, fileName, fileExt] = fileparts(filePath);
            fullName = [fileName,fileExt];
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Initialize
            addpath(genpath('../Declarations'),...
                    genpath('../Functions'),...
                    genpath('../Snippets'),...
                    genpath('../Simulator_3D'),...
                    genpath('../Simulator_1D'));
                
            % Default values
            app.TargetEditField.Value = 3048;
            app.N_hEditField.Value = 100;
            app.N_abEditField.Value = 5;
            app.loadFile(app.RocketFilePath, app.RocketTextArea);
            app.RocketFileField.Value = app.getFileName(app.RocketFilePath);
            app.loadFile(app.EnvironmentFilePath, app.EnvironmentTextArea);
            app.EnvironmentFileField.Value = app.getFileName(app.EnvironmentFilePath);
            
        end

        % Button pushed function: ComputeButton
        function ComputeButtonPushed(app, event)
            % turn computing indicator light on
            app.ComputingLamp.Enable = 'on';
            
            app.MessagesTextArea.Value = {''};
            
            % Initialize simulation
            if(isempty(app.RocketFilePath))
                app.displayMessage('Warning: Define Rocket File first.');
            else
                Rocket = rocketReader(app.RocketFilePath);
            end
            if(isempty(app.EnvironmentFilePath))
                app.displayMessage('Warning: Define Environment File first.');
            else
                Environment = environnementReader(app.EnvironmentFilePath);
            end
            
            SimOutputs = SimOutputReader(app.SimOutputFilePath);
            
            SimObj = Simulator3D(Rocket, Environment, SimOutputs);
            
            
            % -----------------------------------
            % Rail Simulation
            % -----------------------------------
            
            app.displayMessage('Rail Simulation...');
            
            [T1, S1] = SimObj.RailSim();
            
            app.displayMessage('OK');
            
            % -----------------------------------
            % Flight Simulation
            % -----------------------------------
            
            app.displayMessage('Flight Simulation...');
            
            [T2, S2] = SimObj.FlightSim(T1(end), S1(end,2));
            
            app.displayMessage('OK');
            app.displayMessage(['Apogee: ' num2str(max(S2(:,3))) ' m']);
            
            % -----------------------------------
            % Drogue Simulation
            % -----------------------------------
            
            app.displayMessage('Drogue Simulation...');
            
            [T3, S3] = SimObj.DrogueParaSim(T2(end), S2(end,1:3)', S2(end, 4:6)');
            
            app.displayMessage('OK');
            
            % -----------------------------------
            % Main Simulation
            % -----------------------------------
            
            app.displayMessage('Main Simulation...');
            
            [T4, S4] = SimObj.MainParaSim(T3(end), S3(end,1:3)', S3(end, 4:6)');
            
            app.displayMessage('OK');
            
            % -----------------------------------
            % Crash Simulation
            % -----------------------------------
            
            app.displayMessage('Crash Simulation...');
            
            [T5, S5] = SimObj.CrashSim(T2(end), S2(end,1:3)', S2(end, 4:6)');
            
            app.displayMessage('OK');
            
            % -----------------------------------
            % Plots
            % -----------------------------------
            
            app.displayMessage('Rendering...');
            
            % Plot 3D trajectory
            figure; hold on;
            plot3(S2(:,1), S2(:,2), S2(:,3), 'DisplayName', 'Ascent');
            plot3(S3(:,1), S3(:,2), S3(:,3), 'DisplayName', 'Drogue Descent');
            plot3(S4(:,1), S4(:,2), S4(:,3), 'DisplayName', 'Main Descent');
            plot3(S5(:,1), S5(:,2), S5(:,3), 'DisplayName', 'Ballistic Descent')
            daspect([1 1 1]); pbaspect([1, 1, 1]); view(45, 45);
            [XX, YY, M, Mcolor] = get_google_map(Environment.Start_Latitude, Environment.Start_Longitude, 'Height', ceil(diff(xlim)/3.4), 'Width', ceil(diff(ylim)/3.4));
            xImage = [xlim',xlim'];
            yImage = [ylim;ylim];
            zImage = zeros(2);
            colormap(Mcolor);
            surf(xImage, yImage, zImage, 'CData', M,'FaceColor', 'texturemap', 'EdgeColor', 'none', 'DisplayName', 'Base Map');
            title '3D trajectory representation'
            xlabel 'N [m]'; ylabel 'W [m]'; zlabel 'Altitude [m]';
            legend show;
            
            % Plot Altitude vs. drift
            hold(app.TrajectoryAxes, 'off')
            plot(app.TrajectoryAxes, sqrt(S2(:,1).^2 + S2(:,2).^2), S2(:,3), 'DisplayName', 'Ascent');
            hold(app.TrajectoryAxes, 'on')
            plot(app.TrajectoryAxes, sqrt(S3(:,1).^2 + S3(:,2).^2), S3(:,3), 'DisplayName', 'Drogue');
            plot(app.TrajectoryAxes, sqrt(S4(:,1).^2 + S4(:,2).^2), S4(:,3), 'DisplayName', 'Main');
            plot(app.TrajectoryAxes, sqrt(S5(:,1).^2 + S5(:,2).^2), S5(:,3), 'DisplayName', 'Crash');
            legend(app.TrajectoryAxes, 'show')
            
            % Plot altitude vs. time
            hold(app.TimeAltitudeAxes, 'off')
            plot(app.TimeAltitudeAxes, T2, S2(:,3));
            hold(app.TimeAltitudeAxes, 'on')
            plot(app.TimeAltitudeAxes, T3, S3(:,3));
            plot(app.TimeAltitudeAxes, T4, S4(:,3));
            plot(app.TimeAltitudeAxes, T5, S5(:,3));
            
            % Plot velocity vs. time
            V2 = sqrt(S2(:,4).^2+S2(:,5).^2+S2(:,6).^2);
            V3 = sqrt(S3(:,4).^2+S3(:,5).^2+S3(:,6).^2);
            V4 = sqrt(S4(:,4).^2+S4(:,5).^2+S4(:,6).^2);
            V5 = sqrt(S5(:,4).^2+S5(:,5).^2+S5(:,6).^2);
            hold(app.TimeVelocityAxes, 'off')
            plot(app.TimeVelocityAxes, T2, V2);
            hold(app.TimeVelocityAxes, 'on')
            plot(app.TimeVelocityAxes, T3, V3);
            plot(app.TimeVelocityAxes, T4, V4);
            plot(app.TimeVelocityAxes, T5, V5);
            
            % Plot acceleration vs. time
            plot(app.TimeAccelerationAxes, T2(2:end), diff(V2)./diff(T2)/9.81);
            
            % Plot margin vs. time
            plot(app.marginAxes, T2, SimObj.SimAuxResults.Margin)
            
            % Plot AoA vs. time
            plot(app.alphaAxes, T2, SimObj.SimAuxResults.Alpha)
            
            % Plot CNa vs. time
            plot(app.CNaAxes, T2, SimObj.SimAuxResults.Cn_alpha)
            
            % Plot Cd vs. time
            plot(app.CdAxes, T2, SimObj.SimAuxResults.Cd)
            ylim(app.CdAxes, [0, 1.5]);
            tmpYlim = ylim(app.CdAxes);
            set(app.CdAxes, 'YTick', tmpYlim(1):0.1:tmpYlim(2));
            
            % Plot mass vs. time
            plot(app.massAxes, T2, SimObj.SimAuxResults.Mass)
            tmpYlim = ylim(app.massAxes);
            set(app.massAxes, 'YTick', tmpYlim(1):0.5:tmpYlim(2));
            
            % Plot CM vs. time
            plot(app.CMAxes, T2, SimObj.SimAuxResults.CM)
            tmpYlim = ylim(app.CMAxes);
            set(app.CMAxes, 'YTick', tmpYlim(1):0.01:tmpYlim(2));
            
            % Plot Il vs. time
            plot(app.IlAxes, T2, SimObj.SimAuxResults.Il)
            tmpYlim = ylim(app.IlAxes);
            set(app.IlAxes, 'YTick', tmpYlim(1):0.1:tmpYlim(2));
            
            %Plot Ir vs. time
            plot(app.IrAxes, T2, SimObj.SimAuxResults.Ir)
            
            app.displayMessage('OK');
            
            % turn computing indicator light off
            app.ComputingLamp.Enable = 'off';
            
        end

        % Button pushed function: RocketOpenButton
        function RocketOpenButtonPushed(app, event)
             app.RocketFilePath = app.File2TextArea(app.RocketTextArea);
             app.RocketFileField.Value = app.getFileName(app.RocketFilePath);
        end

        % Button pushed function: RocketSaveButton
        function RocketSaveButtonPushed(app, event)
            app.RocketFilePath = app.TextArea2File(app.RocketFilePath, app.RocketTextArea);
            app.RocketFileField.Value = app.getFileName(app.RocketFilePath);
        end

        % Button pushed function: EnvironmentOpenButton
        function EnvironmentOpenButtonPushed(app, event)
            app.EnvironmentFilePath = app.File2TextArea(app.EnvironmentTextArea);
            app.EnvironmentFileField.Value = app.getFileName(app.EnvironmentFilePath);
        end

        % Button pushed function: EnvironmentSaveButton
        function EnvironmentSaveButtonPushed(app, event)
            app.EnvironmentFilePath = app.TextArea2File(app.EnvironmentFilePath, app.EnvironmentTextArea);   
            app.EnvironmentFileField.Value = app.getFileName(app.EnvironmentFilePath);
        end

        % Button pushed function: EnvironmentSaveAsButton
        function EnvironmentSaveAsButtonPushed(app, event)
            app.EnvironmentFilePath = app.TextArea2NewFile(app.EnvironmentTextArea);
            app.EnvironmentFileField.Value = app.getFileName(app.EnvironmentFilePath);
        end

        % Button pushed function: RocketSaveAsButton
        function RocketSaveAsButtonPushed(app, event)
            app.RocketFilePath = app.TextArea2NewFile(app.RocketTextArea);
            app.RocketFileField.Value = app.getFileName(app.RocketFilePath);
        end

        % Button pushed function: AirbrakesComputeButton
        function AirbrakesComputeButtonPushed(app, event)
            Rocket = rocketReader(app.RocketFilePath);
            Environment = environnementReader(app.EnvironmentFilePath);
            
            app.PreviewTextArea.Value = {''};
            
            convert_func = @(x) interp1([-190.5, -18.5], [0, 172] , x, 'linear');
            [~, path] = Table_1D_Generator_R2(Rocket, Environment,...
                app.TargetEditField.Value, @drag_shuriken, convert_func,...
                [-190.5, -18.5], app.N_hEditField.Value,...
                app.N_abEditField.Value, app.AirbrakesAxes);
            
            refreshdata(app.AirbrakesAxes);    
                
            % Display content in text area                
            fid = fopen(path);
            tmpValue = {};
            while(~feof(fid))
                tmpValue{end+1} = fgetl(fid);
            end  
            app.PreviewTextArea.Value = tmpValue;
            app.PreviewTextArea.Value = app.PreviewTextArea.Value(2:end);   
            fclose(fid);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.SetupTab = uitab(app.TabGroup2);
            app.SetupTab.Title = 'Setup';
            app.TabGroup_2 = uitabgroup(app.SetupTab);
            app.RocketTab = uitab(app.TabGroup_2);
            app.RocketTab.Title = 'Rocket';
            app.Label = uilabel(app.RocketTab);
            app.Label.Text = '';
            app.RocketTextArea = uitextarea(app.RocketTab);
            app.RocketOpenButton = uibutton(app.RocketTab, 'push');
            app.RocketOpenButton.ButtonPushedFcn = createCallbackFcn(app, @RocketOpenButtonPushed, true);
            app.RocketOpenButton.Text = 'Open';
            app.RocketSaveButton = uibutton(app.RocketTab, 'push');
            app.RocketSaveButton.ButtonPushedFcn = createCallbackFcn(app, @RocketSaveButtonPushed, true);
            app.RocketSaveButton.Text = 'Save';
            app.RocketSaveAsButton = uibutton(app.RocketTab, 'push');
            app.RocketSaveAsButton.ButtonPushedFcn = createCallbackFcn(app, @RocketSaveAsButtonPushed, true);
            app.RocketSaveAsButton.Text = 'Save as';
            app.FileEditFieldLabel = uilabel(app.RocketTab);
            app.FileEditFieldLabel.Text = 'File : ';
            app.RocketFileField = uieditfield(app.RocketTab, 'text');
            app.RocketFileField.Editable = 'off';
            app.EnvironmentTab = uitab(app.TabGroup_2);
            app.EnvironmentTab.Title = 'Environment';
            app.EnvironmentTextArea = uitextarea(app.EnvironmentTab);
            app.EnvironmentOpenButton = uibutton(app.EnvironmentTab, 'push');
            app.EnvironmentOpenButton.ButtonPushedFcn = createCallbackFcn(app, @EnvironmentOpenButtonPushed, true);
            app.EnvironmentOpenButton.Text = 'Open';
            app.EnvironmentSaveButton = uibutton(app.EnvironmentTab, 'push');
            app.EnvironmentSaveButton.ButtonPushedFcn = createCallbackFcn(app, @EnvironmentSaveButtonPushed, true);
            app.EnvironmentSaveButton.Text = 'Save';
            app.EnvironmentSaveAsButton = uibutton(app.EnvironmentTab, 'push');
            app.EnvironmentSaveAsButton.ButtonPushedFcn = createCallbackFcn(app, @EnvironmentSaveAsButtonPushed, true);
            app.EnvironmentSaveAsButton.Text = 'Save as';
            app.FileEditFieldLabel_2 = uilabel(app.EnvironmentTab);
            app.FileEditFieldLabel_2.Text = 'File : ';
            app.EnvironmentFileField = uieditfield(app.EnvironmentTab, 'text');
            app.EnvironmentFileField.Editable = 'off';
            app.SimulationTab = uitab(app.TabGroup2);
            app.SimulationTab.Title = 'Simulation';
            app.ComputeButton = uibutton(app.SimulationTab, 'push');
            app.ComputeButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeButtonPushed, true);
            app.ComputeButton.Text = 'Compute';
            app.TabGroup = uitabgroup(app.SimulationTab);
            app.TrajectoryTab = uitab(app.TabGroup);
            app.TrajectoryTab.Title = 'Trajectory';
            app.TrajectoryAxes = uiaxes(app.TrajectoryTab);
            xlabel(app.TrajectoryAxes, 'Drift [m]')
            ylabel(app.TrajectoryAxes, 'Altitude [m]')
            app.TimefunctionsTab = uitab(app.TabGroup);
            app.TimefunctionsTab.Title = 'Time functions';
            app.TimeAltitudeAxes = uiaxes(app.TimefunctionsTab);
            xlabel(app.TimeAltitudeAxes, 'time [s]')
            ylabel(app.TimeAltitudeAxes, 'Altitude [m]')
            app.TimeVelocityAxes = uiaxes(app.TimefunctionsTab);
            xlabel(app.TimeVelocityAxes, 'time [s]')
            ylabel(app.TimeVelocityAxes, 'Velocity [m/s]')
            app.TimeAccelerationAxes = uiaxes(app.TimefunctionsTab);
            xlabel(app.TimeAccelerationAxes, 'time [s]')
            ylabel(app.TimeAccelerationAxes, 'Acceleration [g]')
            app.AeroTab = uitab(app.TabGroup);
            app.AeroTab.Title = 'Aero';
            app.marginAxes = uiaxes(app.AeroTab);
            xlabel(app.marginAxes, 't')
            ylabel(app.marginAxes, 'margin [-]')
            app.alphaAxes = uiaxes(app.AeroTab);
            xlabel(app.alphaAxes, 't')
            ylabel(app.alphaAxes, 'alpha [rad]')
            app.CNaAxes = uiaxes(app.AeroTab);
            xlabel(app.CNaAxes, 't')
            ylabel(app.CNaAxes, 'Cn_{alpha} [rad-1]')
            app.CdAxes = uiaxes(app.AeroTab);
            xlabel(app.CdAxes, 't')
            ylabel(app.CdAxes, 'Cd [-]')
            app.MassTab = uitab(app.TabGroup);
            app.MassTab.Title = 'Mass';
            app.massAxes = uiaxes(app.MassTab);
            xlabel(app.massAxes, 't')
            ylabel(app.massAxes, 'mass [kg]')
            app.CMAxes = uiaxes(app.MassTab);
            xlabel(app.CMAxes, 't')
            ylabel(app.CMAxes, 'CM [m]')
            app.IlAxes = uiaxes(app.MassTab);
            xlabel(app.IlAxes, 't')
            ylabel(app.IlAxes, ' I_l [kgm2]')
            app.IrAxes = uiaxes(app.MassTab);
            xlabel(app.IrAxes, 't')
            ylabel(app.IrAxes, 'I_r [kgm2]')
            app.ComputingLampLabel = uilabel(app.SimulationTab);
            app.ComputingLampLabel.Text = 'Computing';
            app.ComputingLamp = uilamp(app.SimulationTab);
            app.ComputingLamp.Enable = 'off';
            app.MessagesTextAreaLabel = uilabel(app.SimulationTab);
            app.MessagesTextAreaLabel.Text = 'Messages';
            app.MessagesTextArea = uitextarea(app.SimulationTab);
            app.MessagesTextArea.Editable = 'off';
            app.AirbrakesTab = uitab(app.TabGroup2);
            app.AirbrakesTab.Title = 'Airbrakes';
            app.AirbrakesAxes = uiaxes(app.AirbrakesTab);
            title(app.AirbrakesAxes, 'Title')
            xlabel(app.AirbrakesAxes, 'X')
            ylabel(app.AirbrakesAxes, 'Y')
            app.TargetmEditFieldLabel = uilabel(app.AirbrakesTab);
            app.TargetmEditFieldLabel.Text = 'Target [m]';
            app.TargetEditField = uieditfield(app.AirbrakesTab, 'numeric');
            app.AirbrakesComputeButton = uibutton(app.AirbrakesTab, 'push');
            app.AirbrakesComputeButton.ButtonPushedFcn = createCallbackFcn(app, @AirbrakesComputeButtonPushed, true);
            app.AirbrakesComputeButton.Text = 'Compute';
            app.PreviewTextAreaLabel = uilabel(app.AirbrakesTab);
            app.PreviewTextAreaLabel.Text = 'Preview';
            app.PreviewTextArea = uitextarea(app.AirbrakesTab);
            app.N_hLabel = uilabel(app.AirbrakesTab);
            app.N_hLabel.Text = 'N_h';
            app.N_hEditField = uieditfield(app.AirbrakesTab, 'numeric');
            app.N_abEditFieldLabel = uilabel(app.AirbrakesTab);
            app.N_abEditFieldLabel.Text = 'N_ab';
            app.N_abEditField = uieditfield(app.AirbrakesTab, 'numeric');
        end
    end

    methods (Access = public)

        % Construct app
        function app = Sim3D_App

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end